classdef testing1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PressureButton  matlab.ui.control.Button
        Image           matlab.ui.control.Image
        AltitudeButton  matlab.ui.control.Button
        Image2          matlab.ui.control.Image
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data table
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.Data = readtable('rocket_data.csv');
           
            
        end

        % Button pushed function: AltitudeButton
        function AltitudeButtonPushed(app, event)
            plot(app.UIAxes, app.Data.Time,app.Data.Altitude, 'LineWidth',1.5);
            title(app.UIAxes, 'Altitude');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Height (m)');
        end

        % Button pushed function: PressureButton
        function PressureButtonPushed(app, event)
            plot(app.UIAxes, app.Data.Time,app.Data.Pressure, 'LineWidth',1.5);
            title(app.UIAxes, 'Pressure');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Pressure (Pa)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Altitude')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'Height (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.5 1];
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2026-04-02 045243.png');
            app.AltitudeButton = uibutton(app.UIFigure, 'push');
            app.AltitudeButton.ButtonPushedFcn = createCallbackFcn(app, @AltitudeButtonPushed, true);
            app.AltitudeButton.Text = 'Altitude';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'mesa.png.png');
            app.PressureButton = uibutton(app.UIFigure, 'push');
            app.PressureButton.ButtonPushedFcn = createCallbackFcn(app, @PressureButtonPushed, true);
            app.PressureButton.Text = 'Pressure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testing1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end